classdef App_EEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        FileMenu        matlab.ui.container.Menu
        OpenMenu        matlab.ui.container.Menu
        QuitMenu        matlab.ui.container.Menu
        AnalysisMenu    matlab.ui.container.Menu
        GraphMenu       matlab.ui.container.Menu
        MainGrid        matlab.ui.container.GridLayout
        GridLayout3     matlab.ui.container.GridLayout
        GridLayout4     matlab.ui.container.GridLayout
        GridLayout6     matlab.ui.container.GridLayout
        DropDown_2      matlab.ui.control.DropDown
        DropDown        matlab.ui.control.DropDown
        GridLayout5     matlab.ui.container.GridLayout
        TimeWindow      matlab.ui.control.Spinner
        TimeOffset      matlab.ui.control.Spinner
        TimeSlider      matlab.ui.control.RangeSlider
        Axes            matlab.ui.control.UIAxes
        TabGroup        matlab.ui.container.TabGroup
        OpenedfilesTab  matlab.ui.container.Tab
        GridLayout      matlab.ui.container.GridLayout
        FileList        matlab.ui.control.ListBox
        ElectrodesTab   matlab.ui.container.Tab
        GridLayout2     matlab.ui.container.GridLayout
        ElectrodeList   matlab.ui.control.ListBox
        RegionList      matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        Files cell = {}
        CurrentFile = 0
        Regions
        IsSliderSyncing logical = false
        CurrentWidth = 1
    end
    
    methods (Access = public)
        function [signal, fs] = getSignal(app, electrode)
            
        end
        
        function file = getFile(app)
            if app.CurrentFile == 0
                file = [];
            else
                file = app.Files{app.CurrentFile};
            end
        end
    end
    
    methods (Access = private)
        function refreshFilesListBox(app)
            n = numel(app.Files);
            if n == 0
                app.FileList.Items = {};
                app.FileList.ItemsData = [];
                app.FileList.Value = {};
                return
            end

            app.FileList.Items = cellfun(@(f) f.Fileinfo.Filename, app.Files);
            app.FileList.ItemsData = 1:n;
            app.FileList.Value = n;

            app.selectFile(n);
        end
        
        function updateElectrodeList(app)
            selRegions = string(app.RegionList.Value);

            if isempty(selRegions)
                app.ElectrodeList.Items = strings(0,1);
                app.ElectrodeList.Value = {};
                return
            end

            electrodes = strings(0,1);

            for r = 1:numel(selRegions)
                regName = selRegions(r);
        
                if isfield(app.Regions, regName)
                    s = app.Regions.(regName);
                    electrodes = [electrodes; string(fieldnames(s))]; %#ok<AGROW>
                end
            end
        
            electrodes = unique(electrodes, "stable");
        
            app.ElectrodeList.Items = electrodes;
        
            if isempty(electrodes)
                app.ElectrodeList.Value = {};
            else
                app.ElectrodeList.Value = electrodes(1);
            end
        end
        
        function selectFile(app, value)
            app.CurrentFile = value;
            app.updateTimeLimits(0);
        end

        function duration = getCurrentDuration(app)
            file = app.getFile();
            if isempty(file)
                duration = 0;
                return
            end
        
            fi = file.Fileinfo;
        
            duration = seconds(fi.DataRecordDuration) * fi.NumDataRecords;
        end
        
        function setWindow(app, offset, width)
            if app.IsSliderSyncing
                return
            end

            duration = app.getCurrentDuration();
            if duration <= 0
                return
            end
        
            if width < 1
                width = 1;
            end
        
            if width > duration
                width = duration;
            end
        
            if offset < 0
                offset = 0;
            end
        
            if offset + width > duration
                offset = duration - width;
            end
        
            endTime = offset + width;
        
            app.IsSliderSyncing = true;
        
            app.CurrentWidth = width;
        
            app.TimeSlider.Limits = [0 duration];
            app.TimeSlider.Value = [offset, endTime];
        
            app.TimeOffset.Limits = [0 duration - 1];
            app.TimeOffset.Value = offset;
        
            app.TimeWindow.Limits = [1 duration - offset];
            app.TimeWindow.Value = width;
        
            app.IsSliderSyncing = false;
        end

        function updateTimeLimits(app, offset)
            duration = app.getCurrentDuration();
            if duration <= 0
                return
            end
        
            width = min(10, duration);
            app.setWindow(offset, width);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Regions.Frontal = struct( ...
                'Fp1','EEGFp1LE','Fp2','EEGFp2LE','F3','EEGF3LE', ...
                'F4','EEGF4LE','F7','EEGF7LE','F8','EEGF8LE','Fz','EEGFzLE');
            app.Regions.Temporal = struct( ...
                'T3','EEGT3LE','T4','EEGT4LE','T5','EEGT5LE','T6','EEGT6LE');
            app.Regions.Parietal = struct( ...
                'P3','EEGP3LE','P4','EEGP4LE','Pz','EEGPzLE');
            app.Regions.Occipital = struct( ...
                'O1','EEGO1LE','O2','EEGO2LE');
            app.Regions.Central = struct( ...
                'C3','EEGC3LE','C4','EEGC4LE','Cz','EEGCzLE');

            app.RegionList.Items = fieldnames(app.Regions);
            app.updateElectrodeList();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filenames, pathname] = uigetfile("*.edf", ...
                "Select an EEG file", MultiSelect='on');

            if isequal(filenames, 0)
                return
            end

            if ischar(filenames)
                filenames = {filenames};
            end

            d = uiprogressdlg(app.UIFigure, ...
                Title="Opening files", Message="Please wait...");

            for k = 1:numel(filenames)
                filepath = fullfile(pathname, filenames{k});

                if iscell(app.Files)
                    already = any(cellfun(@(f) strcmp(f.Filepath, filepath), app.Files));
                else
                    already = any(arrayfun(@(f) strcmp(f.Filepath, filepath), app.Files));
                end

                if already
                    continue
                end

                [hdr,record] = edfread(filepath);
                fileinfo = edfinfo(filepath);
        
                file = struct();
                file.Header = hdr;
                file.Record = record;
                file.Fileinfo = fileinfo;
                file.Filepath = filepath;
    
                app.Files{end+1} = file;

                d.Value = min(1, d.Value + 1/numel(filenames));
            end
            d.Value = 1;
            close(d);

            app.refreshFilesListBox();
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app)
        end

        % Value changed function: RegionList
        function RegionListValueChanged(app, event)
            app.updateElectrodeList();
        end

        % Value changed function: FileList
        function FileListValueChanged(app, event)
            selectFile(app, app.FileList.Value);
        end

        % Menu selected function: GraphMenu
        function GraphMenuSelected(app, event)
            Analysis.Graph(app);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            if app.IsSliderSyncing
                return
            end
        
            newOffset = event.Value(1);
            newEnd = event.Value(2);
            newWidth = newEnd - newOffset;
        
            currentOffset = app.TimeOffset.Value;
            currentEnd = currentOffset + app.CurrentWidth;
        
            if newOffset ~= currentOffset && newEnd == currentEnd
                % déplacement de la poignée gauche : on garde la largeur
                app.setWindow(newOffset, app.CurrentWidth);
            else
                % déplacement de la poignée droite : on change la largeur
                app.setWindow(newOffset, newWidth);
            end
        end

        % Value changed function: TimeOffset
        function TimeOffsetValueChanged(app, event)
            app.setWindow(app.TimeOffset.Value, app.CurrentWidth);
        end

        % Value changed function: TimeWindow
        function TimeWindowValueChanged(app, event)
            app.setWindow(app.TimeOffset.Value, app.TimeWindow.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.AnalysisMenu = uimenu(app.UIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.GraphMenu = uimenu(app.AnalysisMenu);
            app.GraphMenu.MenuSelectedFcn = createCallbackFcn(app, @GraphMenuSelected, true);
            app.GraphMenu.Text = 'Graph';
            app.MainGrid = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.MainGrid);
            app.OpenedfilesTab = uitab(app.TabGroup);
            app.OpenedfilesTab.Title = 'Opened files';
            app.GridLayout = uigridlayout(app.OpenedfilesTab);
            app.FileList = uilistbox(app.GridLayout);
            app.FileList.Items = {};
            app.FileList.ValueChangedFcn = createCallbackFcn(app, @FileListValueChanged, true);
            app.FileList.Value = {};
            app.ElectrodesTab = uitab(app.TabGroup);
            app.ElectrodesTab.Title = 'Electrodes';
            app.GridLayout2 = uigridlayout(app.ElectrodesTab);
            app.RegionList = uilistbox(app.GridLayout2);
            app.RegionList.Items = {''};
            app.RegionList.Multiselect = 'on';
            app.RegionList.ValueChangedFcn = createCallbackFcn(app, @RegionListValueChanged, true);
            app.RegionList.Value = {};
            app.ElectrodeList = uilistbox(app.GridLayout2);
            app.ElectrodeList.Items = {};
            app.ElectrodeList.Multiselect = 'on';
            app.ElectrodeList.Value = {};
            app.GridLayout3 = uigridlayout(app.MainGrid);
            app.Axes = uiaxes(app.GridLayout3);
            title(app.Axes, 'No graph')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            zlabel(app.Axes, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.TimeSlider = uislider(app.GridLayout4, 'range');
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.Step = 1;
            app.TimeSlider.Value = [0 0];
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.TimeOffset = uispinner(app.GridLayout5);
            app.TimeOffset.Limits = [0 0];
            app.TimeOffset.ValueChangedFcn = createCallbackFcn(app, @TimeOffsetValueChanged, true);
            app.TimeWindow = uispinner(app.GridLayout5);
            app.TimeWindow.Limits = [0 0];
            app.TimeWindow.ValueChangedFcn = createCallbackFcn(app, @TimeWindowValueChanged, true);
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.DropDown = uidropdown(app.GridLayout6);
            app.DropDown.Items = {'No filtering', 'Gamma', 'Beta', 'Alpha', 'Teta', 'Delta'};
            app.DropDown.Value = 'No filtering';
            app.DropDown_2 = uidropdown(app.GridLayout6);
            app.DropDown_2.Items = {'db2', 'morse'};
            app.DropDown_2.Value = 'db2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_EEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end